classdef cell_selection_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        suggestedgreenDropDown       matlab.ui.control.DropDown
        suggestedgreenDropDownLabel  matlab.ui.control.Label
        SAVEButton                   matlab.ui.control.Button
        gobacktolastoneButton        matlab.ui.control.Button
        confirmButton                matlab.ui.control.Button
        inputEditField               matlab.ui.control.NumericEditField
        inputEditFieldLabel          matlab.ui.control.Label
        redcellEditField             matlab.ui.control.NumericEditField
        redcellEditFieldLabel        matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end


    properties (Access = private)
        Property % Description
        

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
           %%(redo step 1)load the files and figs
        [figname1,path1a]=uigetfile('.fig','find the figure to be compared');
        [figname2,path2a]=uigetfile('.fig','find the 2nd baseline fig');
        pos=strfind(strtok(figname1,'&'),'_');
        pos_plane = strfind(figname1,'plane');
        nplane = figname1(pos_plane+5);
        name=[figname1(1:pos_plane-2) '&' figname2(1:pos_plane-2) '_plane' nplane];

        fig1=openfig(fullfile(path1a,figname1));
        fig1img =getimage(fig1);
        % fig1img = squeeze(fig1img(:,:,1)); %red channel
        fig1img = fig1img(:,:,2); %green channel
        kC1= fig1.Children.Children;
        cellnum1=(numel(kC1)-1)/2;

        fig2=openfig(fullfile(path2a,figname2));
        fig2img= getimage(fig2);
        % fig2img = squeeze(fig2img(:,:,1)); %red channel
        fig2img = fig2img(:,:,2); %green channel
        kC2= fig2.Children.Children;
        cellnum2=(numel(kC2)-1)/2;

        %%(redo step 2) read the cell position and text info
        fig3=openfig(fullfile(path2a,figname2));
        while menu('Select areas for image alignment?','Yes','Done')==1
            rect=round(getrect(fig3));
            img1=fig1img(rect(2):rect(2)+rect(4),rect(1):rect(1)+rect(3));
            img2=fig2img(rect(2):rect(2)+rect(4),rect(1):rect(1)+rect(3));
            %     max1 = max(img1(:));
            %     min1 = min(img1(:));
            %     max2 = max(img2(:));
            %     min2 = min(img2(:));
            %     img1=(img1-min1)/(max1-min1)*(max2-min2)+min2;
            %     [optimizer, metric] = imregconfig('multimodal');
            %     moving= img1;
            %     fixed = img2;
            %     [moving_reg,R_reg] =  imregister(moving, fixed, 'rigid', optimizer, metric);
            %     tform = imregtform(moving, fixed, 'rigid', optimizer, metric);
            %     v=round(tform.T(3,1));
            %     u=round(tform.T(3,2));
            [u,v] = fftalign(img1,img2);
            fprintf('registration shift(%d,%d)\n',u,v);
            fig1imgnew = circshift(fig1img,[u,v]);
            figure(fig3);
            imshowpair(fig1imgnew,fig2img)
            title('fig1 in red & fig2 in green')
            %     set(fig3,'Position',[0 0 2000 1200]);
        end
    end
    
             %%(redo step 2) read the cell position and text info

            SIZ = size(fig1img);

            F1Contour =zeros(prod(SIZ),cellnum1);
            F2Contour =zeros(prod(SIZ),cellnum2);
            Paninski = strcmp(class(kC1(1)),class(kC1(2)));
            if Paninski
                %1st image cellinfo
                for i=1:cellnum1
                    nth = i*2;
                    F1cell(i).Position = [kC1(nth).Position(1)+v+5,kC1(nth).Position(2)+u+5];
                    F1cell(i).String = kC1(nth).String;
                    F1Contour(:,i) = reshape(circshift(kC1(nth-1).ZData,[u,v]),prod(SIZ),1);
                end
                %2nd image cellinfo
                for i=1:cellnum2
                    nth = i*2;
                    F2cell(i).Position = [kC2(nth).Position(1)-10,kC2(nth).Position(2)-10];
                    F2cell(i).String = kC2(nth).String;
                    F2Contour(:,i) = reshape(kC2(nth-1).ZData,prod(SIZ),1);
                end
            else
                for ii=1:cellnum1
                    nth = 2*cellnum1+1-ii*2;
                    F1cell(ii).Position = [kC1(nth).Position(1)+v+5,kC1(nth).Position(2)+u+5];
                    F1cell(ii).String = kC1(nth).String;
                    tempmask= poly2mask(double(kC1(nth+1).XData),double(kC1(nth+1).YData),SIZ(1),SIZ(2));
                    F1Contour(:,ii) = reshape(circshift(tempmask,[u,v]),[],1);
                end
                for ii=1:cellnum2
                    nth = 2*cellnum2+1-ii*2;
                    F2cell(ii).Position = [kC2(nth).Position(1)+5,kC2(nth).Position(2)+5];
                    F2cell(ii).String = kC2(nth).String;
                    tempmask2= poly2mask(double(kC2(nth+1).XData),double(kC2(nth+1).YData),SIZ(1),SIZ(2));
                    F2Contour(:,ii) = reshape(tempmask2,[],1);
                end
            end
            close all

            %%%first image,red
            fig3=figure()
            imshowpair(fig1imgnew,fig2img)

            title('fig1 in red & fig2 in green')

            hold on;
            for i=1:cellnum1
                %     text(F1cell(i).Position(1),F1cell(i).Position(2),F1cell(i).String,'Color',[ 1 0 0],'FontSize',8)
                contour(1:SIZ(2),1:SIZ(1),reshape(F1Contour(:,i),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[1 0 0])
            end
            %2nd image, green
            for i=1:cellnum2
                %     text(F2cell(i).Position(1),F2cell(i).Position(2),F2cell(i).String,'Color',[ 0 1 0],'FontSize',8)
                contour(1:SIZ(2),1:SIZ(1),reshape(F2Contour(:,i),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[0 1 0])
            end
            %% Deselect unqualified cells first
            x=[];y=[];
            out1=zeros(1,cellnum1);
            out2=zeros(1,cellnum2);
            maskpic1 = zeros(SIZ);
            maskpic2 = zeros(1,prod(SIZ));
            % load(fullfile(path1a,[figname1(1:pos(end)) 'memmap.mat']),'magnification');
            % Set potential good cells criteria: response and size
            try
                sbxread(fullfile(path1a,figname1(1:pos(3)-1)),0,1);
                global info;
                magnification = info.config.magnification;
                load('magnificationlist');
                mag= maglist(magnification)
            catch
                disp('Update magnification otherwise mag=2');
                mag =2;
            end
            load('magnificationlist');

            % cellsize = maglist(magnification)^2*30 *[.6 5] ; NOT USING CELLSIZE as a
            % criteria at the moment JSUN Jan-2020
            % cellsize = magnification^2*40 *4 ;
            %%
            while menu('Deselect?','Yes/Plot','Done')==1
                switch menu('How?','Rect','Pointers','Manuel input(w suggestion)','Plot')
                    case 1
                        rect = round(getrect);
                        maskpic1(rect(2):rect(2)+rect(4),rect(1):rect(1)+rect(3))=1;
                        out1 = out1+reshape(maskpic1,1,prod(SIZ))*F1Contour;
                        out2 = out2+reshape(maskpic1,1,prod(SIZ))*F2Contour;
                    case 2
                        [x,y]= ginput;
                        maskpic2(sub2ind(SIZ, round(y),round(x)))=1;
                        out1 = out1+maskpic2*F1Contour;
                        out2 = out2+maskpic2*F2Contour;
                    case 3
                        prompt = {'1st red','2nd green'};
                        total1 = F1Contour*(out1==0)';
                        total2 = F2Contour*(out2==0)';
                        %             total1=sum(F1Contour,2);
                        %             total2=sum(F2Contour,2);
                        defaultout1 = find(total2'*F1Contour==0); %| sum(F1Contour>.01)>cellsize(2)*4);
                        defaultout2 = find(total1'*F2Contour==0); %| sum(F2Contour>.01)>cellsize(2)*4);
                        default = {num2str(defaultout1),num2str(defaultout2)};
                        figtitle = 'input bad cells';
                        bad=inputdlg(prompt, figtitle,[2 80],default);
                        out1(str2num(bad{1}))=1;
                        out2(str2num(bad{2}))=1;
                    case 4
                        close(fig3)
                        fig3 = figure()
                        imshowpair(fig1imgnew,fig2img)
                        set(gcf,'Position',[0 0 2000 1200])
                        hold on;
                        %first image,red
                        for i=1:cellnum1
                            if out1(i) ==0
                                text(F1cell(i).Position(1),F1cell(i).Position(2),F1cell(i).String,'Color',[ 1 0 0],'FontSize',8)
                                contour(1:SIZ(2),1:SIZ(1),reshape(F1Contour(:,i),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[1 0 0])
                            end
                        end
                        %2nd image, green
                        for i=1:cellnum2
                            if out2(i) ==0
                                text(F2cell(i).Position(1),F2cell(i).Position(2),F2cell(i).String,'Color',[ 0 1 0],'FontSize',8)
                                contour(1:SIZ(2),1:SIZ(1),reshape(F2Contour(:,i),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[0 1 0])
                            end
                        end
                end
            end
            %
            save([name '_cleaned.mat'],'out1','out2');

addpath('C:\Users\SunLab\Box\Enhancement\1847\040417\','_cleaned')

        end

        % Value changed function: redcellEditField
        function redcellEditFieldValueChanged(app, event)
            value = app.redcellEditField.Value;
           %% Select good cells and plot
           prompt = 'red cell';
           figtitle = 'input the green cell# matching the red';
           pair1=[];
           pair2=[];

           figure()
           imshowpair(fig1imgnew,fig2img)
           set(gcf,'Position',[0 0 1000 600]);
           hold on;

           %2nd image, green
           for i=1:cellnum2
               if out2(i) ==0
                   text(F2cell(i).Position(1),F2cell(i).Position(2),F2cell(i).String,'Color',[ 0 1 0],'FontSize',8)
                   contour(1:SIZ(2),1:SIZ(1),reshape(F2Contour(:,i),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[0 1 0])
               end
           end


            i0=1; % the cell to start
            % 1st image,red
            low_overlap_temp = [];
            while i0<=cellnum1
                if out1(i0) ==0
                    text(F1cell(i0).Position(1),F1cell(i0).Position(2),F1cell(i0).String,'Color',[ 1 0 0],'FontSize',8)
                    contour(1:SIZ(2),1:SIZ(1),reshape(F1Contour(:,i0),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[1 0 0])
                    drawnow;
                    default =find((F1Contour(:,i0)'*F2Contour) >0);
                    [~,I] = sort(corr(F1Contour(:,i0),F2Contour(:,default)),'descend' ); %descending correlation number
                    if corr(F1Contour(:,i0),F2Contour(:,default)) < 0.75
                        low_overlap_temp(end+1) = i0;
                    end
                    general_overlap{i0} = corr(F1Contour(:,i0),F2Contour(:,default));
                    %threshold for correlation to check after matching - display only
                    %<75% for example
                    gr=inputdlg({[prompt F1cell(i0).String],'Redo the last one'}, figtitle ,[2 80],{num2str(default(I)),'0'});
                    if str2num(gr{2})==1
                        i0 = i0-1;
                    elseif   ~isempty(gr{1})
                        i2=str2num(gr{1});
                        temp= F1Contour(:,i0).*F2Contour(:,i2);
                        if sum(temp(:)) == 0 || numel(i2)>1
                            disp('wrong pairs? Redo matching'); i0 = i0-1;
                        else
                            pair1(end+1)=i0;
                            pair2(end+1)=i2;
                            text(F1cell(i0).Position(1),F1cell(i0).Position(2),F1cell(i0).String,'Color',[ 1 1 1],'FontSize',8)
                            contour(1:SIZ(2),1:SIZ(1),reshape(F1Contour(:,i0),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[1 1 1])
                            text(F2cell(i2).Position(1),F2cell(i2).Position(2),F2cell(i2).String,'Color',[ 1 1 1],'FontSize',8)
                            contour(1:SIZ(2),1:SIZ(1),reshape(F2Contour(:,i2),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[1 1 1])
                        end
                    else
                        text(F1cell(i0).Position(1),F1cell(i0).Position(2),F1cell(i0).String,'Color',[ 0 0 0],'FontSize',8)
                        contour(1:SIZ(2),1:SIZ(1),reshape(F1Contour(:,i0),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[0 0 0])
                    end
                end
                i0 = i0+1;
            end

        end

        % Drop down opening function: suggestedgreenDropDown
        function suggestedgreenDropDownOpening(app, event)
            
        end

        % Value changed function: inputEditField
        function inputEditFieldValueChanged(app, event)
            value = app.inputEditField.Value;
            
        end

        % Button pushed function: confirmButton
        function confirmButtonPushed(app, event)
            
        end

        % Button pushed function: gobacktolastoneButton
        function gobacktolastoneButtonPushed(app, event)
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            %% (redo step 3) Save all selected pairs
            fig4=figure;
            fig1imgnew = circshift(fig1img,[u,v]);
            imshowpair(fig1imgnew,fig2img)
            set(gcf,'Position',[0 0 1200 1000]);
            hold on;
            %first image,red
            for m=1:numel(pair1)
                text(F1cell(pair1(m)).Position(1),F1cell(pair1(m)).Position(2),F1cell(pair1(m)).String,'Color',[ 1 0 0],'FontSize',8)
                contour(1:SIZ(2),1:SIZ(1),reshape(F1Contour(:,pair1(m)),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[1 0 0])

                text(F2cell(pair2(m)).Position(1),F2cell(pair2(m)).Position(2),F2cell(pair2(m)).String,'Color',[ 0 1 0],'FontSize',8)
                contour(1:SIZ(2),1:SIZ(1),reshape(F2Contour(:,pair2(m)),SIZ(1),SIZ(2)),[0.01 1],'LineColor',[0 1 0])

            end

            selectedname=[name '_selected' num2str(numel(pair1))];
            %%
            saveas(fig4,selectedname,'fig');
            saveas(fig4,selectedname,'png');

            save(selectedname,'pair1','pair2','u','v');
            %
            close(fig4)
            % extra_name =[name '_additional_information.mat'];
            % save(extra_name, 'green_duplicates', 'red_indices', 'general_overlap');
            %% plot &compare twodays
            % plot_twoday_data
        end

        % Value changed function: suggestedgreenDropDown
        function suggestedgreenDropDownValueChanged(app, event)
            value = app.suggestedgreenDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.redcellEditFieldLabel = uilabel(app.UIFigure);
            app.redcellEditFieldLabel.Text = 'red cell';
            app.redcellEditField = uieditfield(app.UIFigure, 'numeric');
            app.redcellEditField.ValueChangedFcn = createCallbackFcn(app, @redcellEditFieldValueChanged, true);
            app.inputEditFieldLabel = uilabel(app.UIFigure);
            app.inputEditFieldLabel.Text = 'input';
            app.inputEditField = uieditfield(app.UIFigure, 'numeric');
            app.inputEditField.ValueChangedFcn = createCallbackFcn(app, @inputEditFieldValueChanged, true);
            app.confirmButton = uibutton(app.UIFigure, 'push');
            app.confirmButton.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirmButton.Text = 'confirm';
            app.gobacktolastoneButton = uibutton(app.UIFigure, 'push');
            app.gobacktolastoneButton.ButtonPushedFcn = createCallbackFcn(app, @gobacktolastoneButtonPushed, true);
            app.gobacktolastoneButton.Text = 'go back to last one';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.suggestedgreenDropDownLabel = uilabel(app.UIFigure);
            app.suggestedgreenDropDownLabel.Text = 'suggested green';
            app.suggestedgreenDropDown = uidropdown(app.UIFigure);
            app.suggestedgreenDropDown.Items = {'Option 1', 'Option 2'};
            app.suggestedgreenDropDown.DropDownOpeningFcn = createCallbackFcn(app, @suggestedgreenDropDownOpening, true);
            app.suggestedgreenDropDown.ValueChangedFcn = createCallbackFcn(app, @suggestedgreenDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cell_selection_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end